classdef GUIcomponents < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CardTable             matlab.ui.control.Image
        SettingsButton        matlab.ui.control.Image
        MapButton             matlab.ui.control.Image
        PlayerTable0          matlab.ui.control.Image
        TaskBarFiller         matlab.ui.control.Label
        TaskBarEmpty          matlab.ui.control.Image
        Deck                  matlab.ui.control.Image
        HelpButton            matlab.ui.control.Image
        CardPile              matlab.ui.control.Image
        PlayerTable4          matlab.ui.control.Image
        PlayerTable3          matlab.ui.control.Image
        PlayerTable2          matlab.ui.control.Image
        PlayerTable1          matlab.ui.control.Image
        SYSTEMMESSAGESPanel   matlab.ui.container.Panel
        TextArea              matlab.ui.control.TextArea
        Task8                 matlab.ui.control.Image
        Task7                 matlab.ui.control.Image
        Task6                 matlab.ui.control.Image
        Task5                 matlab.ui.control.Image
        Task4                 matlab.ui.control.Image
        Task3                 matlab.ui.control.Image
        Task2                 matlab.ui.control.Image
        Task1                 matlab.ui.control.Image
        Shuffle               matlab.ui.control.Button
        PickUpCard            matlab.ui.control.Button
        PLAYButton            matlab.ui.control.StateButton
        TimeDisplay           matlab.ui.control.Label
        STARTSCREENPanel      matlab.ui.container.Panel
        startImage            matlab.ui.control.Image
        STARTButton           matlab.ui.control.Button
        NumberOfPlayersPanel  matlab.ui.container.Panel
        Num5Button            matlab.ui.control.Button
        Num4Button            matlab.ui.control.Button
        Num3Button            matlab.ui.control.Button
        Num2Button            matlab.ui.control.Button
    end

    
    properties (Access = private)
        myPlayerNum    % Player 1 or Player 2?
        otherPlayerNum % Number of other player whose turn is after yours
        channelID      % ThingSpeak Channel ID
        writeKey       % Channel Write API Key
        readKey        % Channel Read API Key
        readDelay      % wait 5 seconds between ThingSpeak Channel Reads
        writeDelay     % wait 5 seconds between ThingSpeak Channel Writings
        taskDeck       % shuffle deck of task cards (no imposter cards in 2 player)
        myLastDrawnCard % card that this player most recently drew
        otherPlayerLastDrawnCard % card that the other player most recently drew
        currentHand    % max 8 cards in a hand
        
        playerNum      % number of players playing, chosen by the user
        
        LogStart       % Logical starting value, only starts if num of players is chosen and start button is clicked
         
        crewImageList %list of image indexes based on the number of players, otherwise value stored is 0
        
        index_crewname_array % list of crewmates
        crewDeck      % "Deck" of crewmate image files
    end

    methods (Access = public)
        
        function app = setImages1(app)
            %if there are less than 8 cards in the hand (obv more than 5 given)
            %then make the spaces for 6 or 7 or 8 cards set to transparent
            if((app.player1.currentHand)<8)
                imageArray1 = repmat({'transparent.gif'},1, 60);
                
            end
        end
        
        %clears any data from previous games in thingspeak channel before you
        %start a new game
        function [] = ClearThingSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values','WriteKey',app.writeKey);
        end
        
        
        function [] = ShuffleDeck(app)
            %randperm returns a row vector 
            %containing a random permutation of the integers from 1 to n
            app.taskDeck = app.taskDeck(randperm(length(app.taskDeck)));
            
        end
        function [] = ResetTaskDeck(app)
            app.taskDeck = [];
            for duplicates = 1:4
                for task = 1:15
                    card.duplicates = duplicates;
                    card.task = task;
                    card.val = 5;
                    app.taskDeck = [app.taskDeck,card];
                end
            end
            app.ShuffleDeck();
        end
        
        function [] = CreateTaskDeck(app)
            index_tasks_array = repmat({[1],[2],[3],[4],[5],[6],[7],[8],[9],[10],[11],[12],[13],[14],[15]},1,4);
            index_array = {1:60};
            prefix_name = cell(1,60);
            imagename_array = cell(1,60);
            for i = 1:60
                prefix_name{i} = strjoin(["ProjTask", index_tasks_array(i)],'');
                imagename_array{i}= strjoin([prefix_name{i}, '.gif'],'');
            end
            app.taskDeck = struct('index', index_array, 'index_tasks_array', index_tasks_array, ...
                 'imagename_array', imagename_array);

             %to access the card project task names
            %for i = 1:60
                %duplicates of task cards are every 15 cards i.e.
                %ProjTask1.gif is card 1 and card 16
                %A_cell = struct2cell(app.taskDeck);
                %D = A_cell(:,:,i);
                %D(3);

             %end 

        end

        
        function [drawnCard] = DrawCard(app)
            drawnCard = app.taskDeck( end );
            app.taskDeck(end)= [];
        end
        
        function [] = ClearPlayerCards(app)
            app.myLastDrawnCard.task = 0;
            app.otherPlayerLastDrawnCard.task = 0;
        end
        
        function [] = CreateCrewStructure(app)
            app.index_crewname_array = {['Pink'],['Red'],['Orange'],['Yellow'],['LGreen'],['Green'],['LBlue'],['Blue'],['Purple'],['Brown']};
            crew_prefix_name = cell(1,10);
            crewimagename_array = cell(1,10);
            for i = 1:10
                crew_prefix_name{i} = strjoin(['Crew', app.index_crewname_array(i)],'');
                crewimagename_array{i}= strjoin([crew_prefix_name(i),'.gif'],'');
            end
            app.crewDeck = struct('CrewImageFileName', crewimagename_array);
            
        end
        
        
        function [] = CrewImageAssign(app)
            randomNum=10;
            while randomNum==10
                randomNum=randi(10,1);
            end
            app.crewImageList=[randomNum,0,0,0,0];
            for i=2:app.playerNum
                app.crewImageList(i)=app.crewImageList(i-1)+1;
                if app.crewImageList(i)==11
                    app.crewImageList(i)=1;
                end
            end
            
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global task;
            task = 1;
            app.STARTSCREENPanel.Visible = "on";
            %app.STARTSCREENPanel.Visible = "on";
            %app.STARTSCREENPanel.Visible = "on";
            app.PLAYButton.Value = 0;
            app.Task6.Enable="off";
            app.Task7.Enable="off";
            app.Task8.Enable="off";
            app.Task6.ImageSource='transparent.gif';
            app.Task7.ImageSource='transparent.gif';
            app.Task8.ImageSource='transparent.gif';
            %set(app.Player1RedButton,'Enable','off');
            %set(app.Player2BlueButton,'Enable','off');
            set(app.STARTButton,'Enable','off');
            if(app.myPlayerNum == 1)
                set(app.PickUpCardPushed, 'Enable','on');
                app.ResetTaskDeck();
            %app.myPlayerNum = 2
            else 
                app.WaitForOtherPlayer();
                app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                    app.GetCardName(app.otherPlayerLastDrawnCard));
                set(app.PickUpCardPushed,'Enable','on');
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.LogStart==true
                app.STARTSCREENPanel.Visible = "off";
                msgbox("Welcome to the ship! You are a CREWMATE.");
                
            else
                errordlg("PLEASE CLICK THE NUMBER OF PLAYERS.")
            end
        end

        % Button pushed function: Num2Button
        function Num2ButtonPushed(app, event)
            app.playerNum=2;
            app.LogStart=true;
            set(app.STARTButton,'Enable','on');
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 2
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource='transparent.gif';
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
                    
            end
        end

        % Button pushed function: Num3Button
        function Num3ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=3;
            app.LogStart=false;
            errordlg("THREE PLAYER MODE IS NOT ENABLED YET. PLEASE BE PATIENT. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 3
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
                    
            end
        end

        % Button pushed function: Num4Button
        function Num4ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=4;
            app.LogStart=false;
            errordlg("FOUR PLAYER MODE IS NOT ENABLED YET. PLEASE BE PATIENT. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 4
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
            end
                 
        end

        % Button pushed function: Num5Button
        function Num5ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=5;
            app.LogStart=false;
            errordlg("FIVE PLAYER MODE IS NOT ENABLED YET. PLEASE BE PATIENT. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 5
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource=string(Crew_cell(:,:,app.crewImageList(4)));
                    app.PlayerTable4.ImageSource=string(Crew_cell(:,:,app.crewImageList(5)));
            end
        end

        % Button pushed function: PickUpCard
        function PickUpCardPushed(app, event)
            app.myLastDrawnCard = app.DrawCard();
            
            app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                app.GetCardName(app.otherPlayerLastDrawnCard));
            
            set(app.PickUpCard,'Enable','off');
            
            app.SendDataToOtherPlayer();
            
            %At this point, player 1 is already displaying both cards 
            %so the cards variables can be set to blank
            if(app.myPlayerNum ==2)
                app.ClearPlayerCards();
            end
            app.waitForOtherPlayer();
            
            %At this point, player 1 is already displaying both cards 
            %so the cards variables can be set to blank
            if(app.myPlayerNum ==1)
                app.ClearPlayerCards();
            end
            app.waitForOtherPlayer();
        end

        % Image clicked function: Task1
        function Task1ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task1.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/1")
            app.Task1.Visible = "off";
            app.Task1.Enable = "off";
            
        end

        % Image clicked function: Task2
        function Task2ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task2.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/2")
            app.Task2.Visible = "off";
            app.Task2.Enable = "off";
        end

        % Image clicked function: Task3
        function Task3ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task3.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/3")
            app.Task3.Visible = "off";
            app.Task3.Enable = "off";
        end

        % Image clicked function: Task4
        function Task4ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task4.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/4")
            app.Task4.Visible = "off";
            app.Task4.Enable = "off";
        end

        % Image clicked function: Task5
        function Task5ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task5.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/5")
            app.Task5.Visible = "off";
            app.Task5.Enable = "off";
        end

        % Image clicked function: Task6
        function Task6ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task6.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/6")
            app.Task6.Visible = "off";
            app.Task6.Enable = "off";
        end

        % Image clicked function: Task7
        function Task7ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task7.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/7")
            app.Task7.Visible = "off";
            app.Task7.Enable = "off";
        end

        % Image clicked function: Task8
        function Task8ImageClicked(app, event)
            global task;
            task = task+1;
            %coordinates of the card pile in middle of the table =
            %(length, width) = (124,182), coordinates: (500,250)
            app.Task8.Position = [525 245 0.75*124 0.75*182];
            msgbox(string(task)+"/8")
            app.Task8.Visible = "off";
            app.Task8.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CardTable = uiimage(app.UIFigure);
            app.CardTable.ImageSource = 'CardTable.gif';
            app.SettingsButton = uiimage(app.UIFigure);
            app.SettingsButton.ImageSource = 'SettingsButton.gif';
            app.MapButton = uiimage(app.UIFigure);
            app.MapButton.ImageSource = 'MapButton.gif';
            app.PlayerTable0 = uiimage(app.UIFigure);
            app.PlayerTable0.ImageSource = 'CrewPurple.gif';
            app.TaskBarFiller = uilabel(app.UIFigure);
            app.TaskBarEmpty = uiimage(app.UIFigure);
            app.TaskBarEmpty.ImageSource = 'TaskBarTransparent.gif';
            app.Deck = uiimage(app.UIFigure);
            app.Deck.ImageSource = 'DeckPile.gif';
            app.HelpButton = uiimage(app.UIFigure);
            app.HelpButton.ImageSource = 'HelpButton.gif';
            app.CardPile = uiimage(app.UIFigure);
            app.CardPile.ImageSource = 'DeckCardsPlayed.gif';
            app.PlayerTable4 = uiimage(app.UIFigure);
            app.PlayerTable4.ImageSource = 'CrewGreen.gif';
            app.PlayerTable3 = uiimage(app.UIFigure);
            app.PlayerTable3.ImageSource = 'CrewBrown.gif';
            app.PlayerTable2 = uiimage(app.UIFigure);
            app.PlayerTable2.ImageSource = 'CrewPink.gif';
            app.PlayerTable1 = uiimage(app.UIFigure);
            app.PlayerTable1.ImageSource = 'CrewBlue.gif';
            app.SYSTEMMESSAGESPanel = uipanel(app.UIFigure);
            app.SYSTEMMESSAGESPanel.Title = 'SYSTEM MESSAGES ';
            app.TextArea = uitextarea(app.SYSTEMMESSAGESPanel);
            app.TextArea.Value = {'Disaster!'};
            app.Task8 = uiimage(app.UIFigure);
            app.Task8.ImageClickedFcn = createCallbackFcn(app, @Task8ImageClicked, true);
            app.Task8.ImageSource = 'ProjTask6.gif';
            app.Task7 = uiimage(app.UIFigure);
            app.Task7.ImageClickedFcn = createCallbackFcn(app, @Task7ImageClicked, true);
            app.Task7.ImageSource = 'ProjTask7.gif';
            app.Task6 = uiimage(app.UIFigure);
            app.Task6.ImageClickedFcn = createCallbackFcn(app, @Task6ImageClicked, true);
            app.Task6.ImageSource = 'ProjTask8.gif';
            app.Task5 = uiimage(app.UIFigure);
            app.Task5.ImageClickedFcn = createCallbackFcn(app, @Task5ImageClicked, true);
            app.Task5.ImageSource = 'ProjTask1.gif';
            app.Task4 = uiimage(app.UIFigure);
            app.Task4.ImageClickedFcn = createCallbackFcn(app, @Task4ImageClicked, true);
            app.Task4.ImageSource = 'ProjTask2.gif';
            app.Task3 = uiimage(app.UIFigure);
            app.Task3.ImageClickedFcn = createCallbackFcn(app, @Task3ImageClicked, true);
            app.Task3.ImageSource = 'ProjTask3.gif';
            app.Task2 = uiimage(app.UIFigure);
            app.Task2.ImageClickedFcn = createCallbackFcn(app, @Task2ImageClicked, true);
            app.Task2.ImageSource = 'ProjTask4.gif';
            app.Task1 = uiimage(app.UIFigure);
            app.Task1.ImageClickedFcn = createCallbackFcn(app, @Task1ImageClicked, true);
            app.Task1.ImageSource = 'ProjTask5.gif';
            app.Shuffle = uibutton(app.UIFigure, 'push');
            app.Shuffle.Icon = 'ShuffleCard.gif';
            app.Shuffle.Text = '';
            app.PickUpCard = uibutton(app.UIFigure, 'push');
            app.PickUpCard.ButtonPushedFcn = createCallbackFcn(app, @PickUpCardPushed, true);
            app.PickUpCard.Icon = 'DeckButton.gif';
            app.PickUpCard.Text = '';
            app.PLAYButton = uibutton(app.UIFigure, 'state');
            app.PLAYButton.Icon = 'CrewWhite.gif';
            app.PLAYButton.Text = 'PLAY';
            app.PLAYButton.Value = true;
            app.TimeDisplay = uilabel(app.UIFigure);
            app.TimeDisplay.Text = '  TIME LEFT:';
            app.STARTSCREENPanel = uipanel(app.UIFigure);
            app.STARTSCREENPanel.Title = 'START SCREEN';
            app.STARTSCREENPanel.Visible = 'off';
            app.startImage = uiimage(app.STARTSCREENPanel);
            app.startImage.ImageSource = 'AmongUsTitlePage.gif';
            app.STARTButton = uibutton(app.STARTSCREENPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NumberOfPlayersPanel = uipanel(app.STARTSCREENPanel);
            app.NumberOfPlayersPanel.Title = 'Number Of Players';
            app.Num5Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num5Button.ButtonPushedFcn = createCallbackFcn(app, @Num5ButtonPushed, true);
            app.Num5Button.Text = '5';
            app.Num4Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num4Button.ButtonPushedFcn = createCallbackFcn(app, @Num4ButtonPushed, true);
            app.Num4Button.Text = '4';
            app.Num3Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num3Button.ButtonPushedFcn = createCallbackFcn(app, @Num3ButtonPushed, true);
            app.Num3Button.Text = '3';
            app.Num2Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num2Button.ButtonPushedFcn = createCallbackFcn(app, @Num2ButtonPushed, true);
            app.Num2Button.Text = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIcomponents

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end